classdef Current_Deconvolution_Cyclic_Voltammetry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        PlotontoratemVsDropDown       matlab.ui.control.DropDown
        PlotontoratemVsDropDownLabel  matlab.ui.control.Label
        RateTable                     matlab.ui.control.Table
        TabGroup                      matlab.ui.container.TabGroup
        LoadDataTab                   matlab.ui.container.Tab
        UpperVLimitEditField          matlab.ui.control.NumericEditField
        UpperVLimitEditFieldLabel     matlab.ui.control.Label
        LowerVLimitEditField          matlab.ui.control.NumericEditField
        LowerVLimitEditFieldLabel     matlab.ui.control.Label
        LoadFormattedDataButton       matlab.ui.control.Button
        FilepathEditField             matlab.ui.control.EditField
        BrowseDataButton              matlab.ui.control.Button
        FormatDataTab                 matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        OpenDataFormatterButton       matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        ClearPlotButton               matlab.ui.control.Button
        pdfDropDown                   matlab.ui.control.DropDown
        SaveFigureButton_2            matlab.ui.control.Button
        DeconvoluteCurrentwithoutpeakpositioncorrectionButton  matlab.ui.control.Button
        PeakpositioncorrectionButton  matlab.ui.control.Button
        SaveEditField                 matlab.ui.control.EditField
        SaveDropDown                  matlab.ui.control.DropDown
        GetTableButton                matlab.ui.control.Button
        DeconvoluteCurrentwithpeakpositioncorrectionButton  matlab.ui.control.Button
        SaveFigureButton              matlab.ui.control.Button
        VariableRateCVLabel           matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        TT_in
        fxR
        fxO
        red
        ox
        V
        rate

        total_list
        
        redox_mask
        
        pseudo
        capacit
        farad
        fx
        
        hf
        
        h1
        x1
        p1
        xvals

        E_red_all
        E_ox_all
        hasCal % peak shifted? (0 = False, 1 = True)
    end
    
    methods (Access = private)
        
        function [data, voltage_col, current_col, nc_col, Ns_changes_col, time_col] = ECLabread(app, filename)
            try
                data = readtable(filename,'PreserveVariableNames',true);
            catch ME
                warning(strcat('The following file could not be found: ',filename));
                set(app.FilepathEditField,'Value','Input Correct File Path')
                return
            end
            header=data.Properties.VariableNames;
            data = table2array(data);
            [voltage_col, current_col, nc_col, Ns_changes_col, time_col] = ECLabHeaderRead(app, header);
        end
        function [voltage_col, current_col, nc_col, Ns_changes_col, time_col] = ECLabHeaderRead(app, header)
            voltage_col = contains(header,'Ewe');
            current_col = contains(header,'<I>/mA');
            nc_col = contains(header,'cycle number');
            Ns_changes_col = contains(header,'Ns changes');
            time_col = contains(header,'time/s');
        end
        function [red, ox, rate] = CVspline(app, x, y, V, time)

            xmin = find(x==min(x),1);
            xmax = find(x==max(x),1);
            if xmin>xmax
                tempmax = xmax;
                xmax = xmin;
                xmin = tempmax;
                redoxflip = 1;
            else
                redoxflip = 0;
            end
            
            [xtopunique,jj] = unique(x(xmin:xmax));
            ytop=y(xmin:xmax);
            ytopunique = ytop(jj);
            
            xbottom = [x(xmin-1:-1:1); x(end:-1:xmax+1)];
            ybottom = [y(xmin-1:-1:1); y(end:-1:xmax+1)];
            
            [xbottomunique, i] = unique(xbottom);
            ybottomunique = ybottom(i);
            
            timetop = time(xmin:xmin+length(xtopunique)-1);
            
            rate = round((xtopunique(end)-xtopunique(2))/(timetop(end)-timetop(2))*1000,2,'significant');
            
            if redoxflip == 0
                red = interp1(xbottomunique,ybottomunique,V);
                ox = interp1(xtopunique,ytopunique,V);
            elseif redoxflip == 1
                ox = interp1(xbottomunique,ybottomunique,V);
                red = interp1(xtopunique,ytopunique,V);
            end
                
        end
        
        function results = p_plot_CV(app)
            V = app.V;
            V = V(1:end);
            red = app.red;
            ox = app.ox;
                        
            rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
            
            for i = 1:length(rates_TT)
                if rates_TT(i) == 1
%                     ox(:,i) = sgolayfilt(ox(:,i),1,501);
%                     red(:,i) = sgolayfilt(red(:,i),1,501);
                    appendredoxstart = find(~isnan(ox(:,i)),1,'first');
                    appendredoxend = find(~isnan(ox(:,i)),1,'last');
                    red(appendredoxstart,i) = ox(appendredoxstart,i);
                    red(appendredoxend,i) = ox(appendredoxend,i);
                end
            end
            
            lowerlim = app.LowerVLimitEditField.Value;
            upperlim = app.UpperVLimitEditField.Value;

%             baseline_correct = ox(find(~isnan(ox),1),rates_TT)
%             y = [red(1:50:end,rates_TT)-baseline_correct(1,rates_TT); ox(1:50:end,rates_TT)-baseline_correct(1,rates_TT)];
            
            V = [V,V]';
            y = [red(1:end,rates_TT); ox(1:end,rates_TT)];
            
            maxlim = max(max(ox(:,rates_TT)));
            minlim = min(min(red(:,rates_TT)));
            tickspace = abs(round((maxlim-minlim)/7,1,'significant'));
            % tickspacex = abs(round((upperlim-lowerlim)/7,1,'significant'));
            mintick = round(floor((minlim)/tickspace)*tickspace,2,'significant');
            maxtick = round(ceil((maxlim)/tickspace)*tickspace,2,'significant');
                    
            cla(app.UIAxes)
            ax = app.UIAxes;
            app.UIAxes.ColorOrder = [0 0 0; 1 0 0; 0 0 1; 0.73 0.21 0.73;...
                   0.47 0.67 0.19; 0.85 0.33 0.10; 1 0 1; 0.64 0.08 0.18; ...
                   .61 .51 .74; 0 1 0; 0 1 1];
    
            h1 = plot(ax,V,y,'LineWidth',3,'Tag','CVData');
                    
            ax.FontWeight = 'bold';
            ax.LineWidth = 2;
            % ax.XTick = [-9*tickspacex:tickspacex:9*tickspacex];
            ax.XLabel.String = 'Potential vs. ref (V)';
            ax.YLabel.String = 'Current, \itI\rm/mA';
            ax.XLim = [lowerlim-(tickspace/2) upperlim+(tickspace/2)];
            ax.YLim = [mintick-(tickspace/2) maxtick+(tickspace/2)];
            % ax.YTick = [-9*tickspace:tickspace:9*tickspace];

            % load legend when selected rates are changed
            legend(app.UIAxes, strcat(app.PlotontoratemVsDropDown.Items," mV/s"), 'Location','best','FontSize',24)

            disp('Variable Rate CV Plotting Complete')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            app.hasCal = 0;
            
            [fList,pList] = matlab.codetools.requiredFilesAndProducts(mfilename);
            pname = {pList.Name};
            v = ver;
            for i = 2:1:length(pname)
                dependency_test = any(strcmp({v.Name}, pname{i}));
                if dependency_test
                    display("Dependency satisfied: "+pname{i}+".")
                else
                    errordlg("Dependency not satisfied. Please install "+pname{i}+" to continue.")
                    break
                end
            end
        end

        % Button pushed function: BrowseDataButton
        function p_browse(app, event)
            [filename, filepath] = uigetfile(strcat(pwd,'/*.txt'),'Choose the ECLab Data File');
            if filename == 0
                set(app.FilepathEditField,'Value','Input Data')
            else
                disp(['User selected file: ', fullfile(filename)]);
                set(app.FilepathEditField,'Value',fullfile(filepath,filename))
            end
        end

        % Button pushed function: LoadFormattedDataButton
        function p_load(app, event)
            filename = app.FilepathEditField.Value;
            lowerlim = app.LowerVLimitEditField.Value;
            upperlim = app.UpperVLimitEditField.Value;
            
            try
                [M, voltage_col, current_col, nc_col, ~, time_col] = ECLabread(app,filename);
            catch
                warning("Please ensure an appropriate file path was chosen.")
                return
            end
            
            V = linspace(lowerlim, upperlim, 4000);
            
            cn = unique(M(:,nc_col));
            cnlength = length(cn);
            time1 = M(:,time_col);
            
            row_i = nan(cnlength+1,1);
            row_end = length(time1);
            for ii = 1:cnlength
                row_i(ii) = find(M(:,nc_col)==cn(ii),1);
            end
            row_i(cnlength+1) = row_end;
            
            red  = nan(length(V),cnlength);
            ox   = nan(length(V),cnlength);
            rate = nan(cnlength,1);
            
            for j = 1:cnlength
                a = M(row_i(j):row_i(j+1)-1,:);
                TT_cycle = a(:,nc_col)==cn(j);
                a = a(TT_cycle,:);
                time = a(:,time_col);
                v = a(:,voltage_col);
                I = a(:,current_col);
            
                [red(:,j), ox(:,j), rate(j)] = CVspline(app,v,I,V,time);
            
                appendredoxstart = find(~isnan(ox(:,j)),1,'first');
                appendredoxend   = find(~isnan(ox(:,j)),1,'last');
            
                percentage_of_points = 0.015;
                filter_modulation = 2*(floor((appendredoxend-appendredoxstart)*percentage_of_points/2))+1;
            
                v = ver;
                has_fsolve = any(strcmp({v.Name}, 'Signal Processing Toolbox'));
                if has_fsolve & filter_modulation > 1
                    ox(:,j)  = sgolayfilt(ox(:,j),1,filter_modulation);
                    red(:,j) = sgolayfilt(red(:,j),1,filter_modulation);
                end
            
                red(appendredoxstart,j) = ox(appendredoxstart,j);
                red(appendredoxend,j)   = ox(appendredoxend,j);
            end
            
            uniquerateorder = unique(rate);
            number_of_rates = length(uniquerateorder);
            
            redorder = nan(length(V),number_of_rates);
            oxorder  = nan(length(V),number_of_rates);
            fxR     = nan(length(V),number_of_rates);
            fxO     = nan(length(V),number_of_rates);
            
            idx = nan(size(uniquerateorder));
            for iii = 1:number_of_rates
                idx(iii) = find(rate==uniquerateorder(iii),1,'last');
            end
            
            for k = 1:1:number_of_rates
                redorder(:,k) = red(:,idx(k));
                oxorder(:,k)  = ox(:,idx(k));
                ratenew(k)    = rate(idx(k));
            end
            red = redorder;
            ox  = oxorder;
            
            logik = cell(number_of_rates,1);
            logik(:) = {true};
            total_list = zeros(number_of_rates,1);
            total_list(1,1) = 1;
            
            vars = {'Rate (mV/s)','Include'};
            
            app.RateTable.Data = table(uniquerateorder,logik);
            app.RateTable.ColumnName = vars;
            app.PlotontoratemVsDropDown.Items = string(uniquerateorder);
            app.PlotontoratemVsDropDown.Value = app.PlotontoratemVsDropDown.Items(1);
            app.red  = red;
            app.ox   = ox;
            app.fxR  = fxR;
            app.fxO  = fxO;
            app.V    = V;
            app.rate = ratenew;
            app.total_list = string(uniquerateorder);
            app.hasCal = 0;
            
            [~,name,ext] = fileparts(filename);
            disp(['Loaded File: ',name,ext])
            
            p_plot_CV(app)
            legend(app.UIAxes, strcat(string(app.total_list)," mV/s"), 'Location','best','FontSize',24)
            app.UIAxes.FontSize        = 20;
            app.UIAxes.XLabel.FontSize = 20;
            app.UIAxes.YLabel.FontSize = 20;
            app.UIAxes.Title.FontSize  = 20;
            
            % ================== AXIS CLEANUP: fix crowded x-axis (keep fonts) ==================
            ax = app.UIAxes;
            
            % Limits with small padding
            x_min = lowerlim;
            x_max = upperlim;
            y_min = min([app.red(:); app.ox(:)], [], 'omitnan');
            y_max = max([app.red(:); app.ox(:)], [], 'omitnan');
            if ~isfinite(y_min) || ~isfinite(y_max) || y_min==y_max
                y_min = -1; y_max = 1;
            end
            xr = x_max - x_min; if xr<=0, xr = 1; end
            yr = y_max - y_min; if yr<=0, yr = 1; end
            
            xpad = 0.03*xr;
            ypad = 0.05*yr;
            
            xlim(ax, [x_min - xpad, x_max + xpad]);
            ylim(ax, [y_min - ypad, y_max + ypad]);
            
            % Fewer x-ticks and clean formatting (fonts unchanged)
            ax.XTick = linspace(x_min, x_max, 7);   % ~6–8 ticks is plenty
            xtickformat(ax,'%.2f');
            xtickangle(ax,0);
            ax.YTickMode  = 'auto';
            ax.XMinorTick = 'off';
            ax.YMinorTick = 'off';
            
            % Give extra room for large labels so they don't collide with the axes box
            ax.Units    = 'normalized';
            ax.Position = [0.12 0.20 0.74 0.72];    % [left bottom width height]
            % ================================================================================

        end

        % Cell edit callback: RateTable
        function chooserates(app, event)
            indices = event.Indices;
            newData = event.NewData;
            total_list = app.total_list;
            
            selected_rates_mask = cell2mat(table2array(app.RateTable.Data(:,2)));
            total_selected = sum(selected_rates_mask);
            if total_selected ~= 0

                newlist = total_list(selected_rates_mask);
                app.PlotontoratemVsDropDown.Items = newlist;

            else
                error('At least one rate must be selected.')
            end
            
            p_plot_CV(app)
        end

        % Button pushed function: 
        % DeconvoluteCurrentwithpeakpositioncorrectionButton
        function p_plot(app, event)
            V    = app.V;
            red  = app.red;
            ox   = app.ox;
            rate = app.rate;
            hasCal = app.hasCal;
            
            lowerlim = app.LowerVLimitEditField.Value;
            upperlim = app.UpperVLimitEditField.Value;
            
            disp('Calculating data to plot')
            disp('hasCal:'+string(hasCal))
            
            % hasCal = isgraphics(app.UIFigure) && ...
            %          isappdata(app.UIFigure,'E_red') && isappdata(app.UIFigure,'E_ox');
            
            if hasCal
                % E_red_all = getappdata(app.UIFigure,'E_red');   % N×S
                % E_ox_all  = getappdata(app.UIFigure,'E_ox');    % N×S
                E_red_all = app.E_red_all;
                E_ox_all = app.E_ox_all;
            else
                if isvector(V), Vcol = V(:); else, Vcol = V(:,1); end
                S_all = size(ox,2);
                E_red_all = repmat(Vcol,1,S_all);   % N×S
                E_ox_all  = repmat(Vcol,1,S_all);   % N×S
            end
            
            % Table of chosen rates
            rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)))';
            % Scan rate chosen for plotting
            pickrate = str2double(app.PlotontoratemVsDropDown.Value);

            rate = rate(rates_TT);
            ratepick = rate == pickrate;  

            red = red(:,rates_TT);
            ox  = ox(:,rates_TT);

            E_red = E_red_all(:,rates_TT);   % N×S_filtered
            E_ox  = E_ox_all(:,rates_TT);    % N×S_filtered
            

            for i = 1:length(ratepick)
                red(1,i)  = ox(1,i);
                red(end,i)= ox(end,i);
            end
            
            
            % Initialize matrices for individual contributions
            Npts = size(red,1);
            capacitativeRed = NaN(Npts,1);
            faradaicRed     = NaN(Npts,1);
            pseudoRed       = NaN(Npts,1);
            
            capacitativeOx  = NaN(Npts,1);
            faradaicOx      = NaN(Npts,1);
            pseudoOx        = NaN(Npts,1); 

            % transposing matrices for lsqcurvefit
            x = rate(1,:)';            
            for kk = 1:Npts
                yRed = abs(red(kk,:))';
                yOx  = abs(ox(kk,:))';
            
                if (any(isnan(yRed)) || any(isnan(yOx)))
                    disp("Data point " +string(kk)+" has a NaN value.")
                    continue
                end
            
                customFunc   = @(p, x) p(1)*x.^0.5 + p(2)*x + p(3)*x.^1.5;
                initialGuess = [0, 0, 0];
                lb = [0, 0, 0];
                ub = [Inf, Inf, Inf];
            
                options = optimoptions('lsqcurvefit', ...
                    'FunctionTolerance', 1e-6, ...
                    'StepTolerance', 1e-6, ...
                    'OptimalityTolerance', 1e-6, ...
                    'Display', 'off');
            
                % Fit the data
                pRed = lsqcurvefit(customFunc, initialGuess, x, yRed, lb, ub, options);
                pOx  = lsqcurvefit(customFunc, initialGuess, x, yOx,  lb, ub, options);
            
                if (mod(kk,10) == 0)
                    disp('Oxidation coefficients (a, b, c):');
                    disp(pOx);
                end
            
                currentSignRed = sign(red(kk,ratepick));
                currentSignOx  = sign(ox(kk,ratepick));
            
                faradaicRed(kk)     = currentSignRed * pRed(1,1) * pickrate^0.5;
                capacitativeRed(kk) = currentSignRed * pRed(1,2) * pickrate;
                pseudoRed(kk)       = currentSignRed * pRed(1,3) * pickrate^1.5;
            
                faradaicOx(kk)      = currentSignOx * pOx(1,1) * pickrate^0.5;
                capacitativeOx(kk)  = currentSignOx * pOx(1,2) * pickrate;
                pseudoOx(kk)        = currentSignOx * pOx(1,3) * pickrate^1.5;
            
                if (mod(kk,10) == 0 || kk > Npts * 0.9)
                    disp("Point "+string(kk)+" of "+string(Npts))
                end
            end
            
            V = cat(1, E_red(:,ratepick), flip(E_ox(:,ratepick),1));         % 2N×1
            fx = cat(1, red(:,ratepick),   flip(ox(:,ratepick),1));          % 2N×1
            
            farad   = cat(1, faradaicRed,     flip(faradaicOx,1));
            capacit = cat(1, capacitativeRed, flip(capacitativeOx,1));
            pseudo  = cat(1, pseudoRed,       flip(pseudoOx,1));

            maxlim = max(max(ox(:,ratepick)));
            minlim = min(min(red(:,ratepick)));
            tickspace  = abs(round((maxlim-minlim)/7,1,'significant'));
            tickspacex = abs(round((upperlim-lowerlim)/7,1,'significant'));
            mintick = round(floor((minlim)/tickspace)*tickspace,2,'significant');
            maxtick = round(ceil((maxlim)/tickspace)*tickspace,2,'significant');

            disp("V: "+string(size(V)))
            disp("Fx: "+string(size(fx)))
            disp("farad: "+ string(size(farad)))
            disp("capacit: "+ string(size(capacit)))
            disp("pseudo: "+ string(size(pseudo)))

            hf = figure;
            hold on
            % Total current
            h1 = plot(V, fx, '.-', 'color', 'k', 'LineWidth', 3);

            % New Custom Colors (orange, blue, green)
            customHex = {'#d95f02', '#7570b3','#1b9e77'};
            customColors = zeros(3,3);

            % convert hexcodes into rgb
            for k = 1:3
                customColors(k,:) = sscanf(customHex{k}(2:end), '%2x%2x%2x', [1 3]) / 255;
            end

            fColor = customColors(1,:);
            cColor = customColors(2,:);
            pColor = customColors(3,:);
            
            AreaTest = 1; % (individual curves AreaTest == 0, stacked areas AreaTest == 1)
            if AreaTest == 1
                Yred = cat(2, faradaicRed, capacitativeRed, pseudoRed);
                Yox = cat(2, faradaicOx, capacitativeOx, pseudoOx);
    
                X = repmat(app.V, size(Yred, 2), 1)'; % Vx3

                hred = area(X,Yred,'FaceAlpha', 0.4, 'LineWidth', 1.5); % reduction areas
                hox = area(X,Yox,'FaceAlpha', 0.4, 'LineWidth', 1.5); % oxidation areas

                % face and edge colors need to be set element wise
                for k = 1:numel(hred) % always 1-3
                    hred(k).FaceColor = customColors(k,:);
                    hred(k).EdgeColor = customColors(k,:);

                    hox(k).FaceColor = customColors(k,:);
                    hox(k).EdgeColor = customColors(k,:);
                end
            else
                keepIndexF = ~isnan(farad);
                keepIndexC = ~isnan(capacit);
                keepIndexP = ~isnan(pseudo);
    
                % Individual Contributions (filled patches)
                patch(V(keepIndexP), pseudo(keepIndexP), pColor,'FaceAlpha', 0.4, 'EdgeColor', pColor, 'LineWidth', 1.5);
                patch(V(keepIndexC), capacit(keepIndexC),  cColor,'FaceAlpha', 0.4, 'EdgeColor', cColor,  'LineWidth', 1.5);
                patch(V(keepIndexF), farad(keepIndexF), fColor,   'FaceAlpha', 0.4, 'EdgeColor', fColor,   'LineWidth', 1.5);
    
                disp("FaradaicRed: "+num2str(faradaicRed(1))+" End: "+num2str(faradaicRed(end)))
                disp("FaradaicOx: "+num2str(faradaicOx(1))+" End: "+num2str(faradaicRed(end)))
            end

            % Legend with clean labels
            l1 = plot(NaN, NaN, 'LineWidth', 3, 'Color', pColor);
            l2 = plot(NaN, NaN, 'LineWidth', 3, 'Color', cColor);
            l3 = plot(NaN, NaN, 'LineWidth', 3, 'Color', fColor);

            legend([h1(1), l1, l2, l3], ...
                   'Total Current', ...
                   'Pseudocapacitive Current', ...
                   'Capacitive Current', ...
                   'Faradaic Current', ...
                   'FontSize', 20, 'FontWeight', 'normal', 'Location', 'northwest');
            legend box off
        
            ax = gca;
            xlabel('Electrode potential, 𝑬 𝒗𝒔. ref/V')
            ylabel('Current, 𝐼/mA')
            ax.FontWeight = 'bold';
            ax.FontSize = 24;
            ax.LineWidth = 2;
            ax.XTick = [-9*tickspacex:tickspacex:9*tickspacex];
            ax.XLim  = [lowerlim-(tickspace/2) upperlim+(tickspace/2)];
            ax.YLim  = [mintick-(tickspace/2)  maxtick+(tickspace/2)];
            ax.YTick = [-9*tickspace:tickspace:9*tickspace];
            ax.XMinorTick = 'on';
            ax.YMinorTick = 'on';
            text(ax,0.1,0.05,strcat(string(pickrate), ' mV/s'), 'Units', 'normalized', ...
                'Position', [-0.1 -0.05], 'FontSize',18, 'HorizontalAlignment',"center",'VerticalAlignment',"middle")
            legend box 'off'

            % Display Message
            if app.hasCal == 0; disp('CV Plot Completed without peak position correction'); end
            disp('Deconvoluted-current CV Plot Complete')

            app.hf     = hf;
            app.pseudo = pseudo;
            app.capacit= capacit;
            app.farad  = farad;
            app.fx     = fx;
        end

        % Button pushed function: GetTableButton
        function Get_Capacitance(app, event)
            V       = app.V(:);                % N×1 potential grid
            red_all = app.red;                 % N×M
            ox_all  = app.ox;                  % N×M
            rate_all= app.rate(:);             % M×1
            capacit = app.capacit;             % either 2N×1 or N×2
            farad   = app.farad;               % either 2N×1 or N×2
            pseudo  = app.pseudo;              % either 2N×1 or N×2
        
            % Basic checks
            if isempty(V) || isempty(red_all) || isempty(ox_all) || isempty(rate_all)
                error('Deconvolution inputs not found. Run a deconvolution step first.');
            end
            if isempty(capacit) || isempty(farad) || isempty(pseudo)
                error(['Deconvolved components (capacit/farad/pseudo) are empty. ' ...
                       'Please run the deconvolution step before "Get Table".']);
            end
        
            N = numel(V);
        
            rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));  % logical mask
            if ~any(rates_TT)
                error('At least one rate must be selected in the table.');
            end
            rate_sel = rate_all(rates_TT);
            red_sel  = red_all(:,rates_TT);
            ox_sel   = ox_all(:,rates_TT);
        
            pickrate_mVs = str2double(app.PlotontoratemVsDropDown.Value); % mV/s
            if isnan(pickrate_mVs)
                error('No plot rate chosen. Pick a rate in the dropdown first.');
            end
            nu = pickrate_mVs/1000;  % V/s
            ratepick = (rate_sel == pickrate_mVs);
            if ~any(ratepick)
                error('Chosen plot rate is not among the selected rates.');
            end
        
            red = red_sel(:,ratepick);
            ox  = ox_sel(:,ratepick);
        
            function Q = safe_charge(x, y)
                m = ~isnan(x) & ~isnan(y);
                x1 = x(m); y1 = y(m);
                if isempty(x1)
                    Q = 0;
                    return
                end
                [xs, idx] = sort(x1(:), 'ascend');  % ensure increasing x for trapz
                ys = abs(y1(idx));
                Q = trapz(xs, ys) / nu;            % mA·s  (since current in mA, x in V, divide by V/s)
            end
        
            Q_red_fix = safe_charge(V, red);
            Q_ox_fix  = safe_charge(V, ox);
            Q_Total   = Q_red_fix + Q_ox_fix;
        
            function [xr, yr, xo, yo] = split_component(comp)
                sz = size(comp);
                if isvector(comp) && numel(comp) == 2*N
                    comp = comp(:);
                    yr = comp(1:N);         xr = V;            % RED half vs V
                    yo = comp(N+1:end);     xo = flip(V);      % OX half stored in reverse → use flip(V)
                elseif sz(1) == N && sz(2) >= 2
                    yr = comp(:,1);         xr = V;            % RED column vs V
                    yo = comp(:,2);         xo = V;            % OX column vs V (assume already aligned)
                else
                    error('Unexpected component shape. Expected 2N×1 or N×2.');
                end
            end
        
            [xCR, yCR, xCO, yCO] = split_component(capacit);
            [xFR, yFR, xFO, yFO] = split_component(farad);
            [xPR, yPR, xPO, yPO] = split_component(pseudo);
        
            Q_Cap_Red   = safe_charge(xCR, yCR);
            Q_Cap_Ox    = safe_charge(xCO, yCO);
            Q_Farad_Red = safe_charge(xFR, yFR);
            Q_Farad_Ox  = safe_charge(xFO, yFO);
            Q_Pseudo_Red= safe_charge(xPR, yPR);
            Q_Pseudo_Ox = safe_charge(xPO, yPO);
        
            % Totals
            Q_farad_Total   = Q_Farad_Ox   + Q_Farad_Red;
            Q_Cap_Total     = Q_Cap_Ox     + Q_Cap_Red;
            Q_pseudo_Total  = Q_Pseudo_Ox  + Q_Pseudo_Red;
        
            Q_Total_Ox      = Q_Cap_Ox + Q_Farad_Ox + Q_Pseudo_Ox;
            Q_Total_Red     = Q_Cap_Red + Q_Farad_Red + Q_Pseudo_Red;
            Q_Total_Calc    = Q_Total_Ox + Q_Total_Red;
        
            pct = @(num,den) (den~=0).*100.*num./max(den, eps);
        
            Percent_Q_Farad_Red    = pct(Q_Farad_Red,  Q_Total_Red);
            Percent_Q_Farad_Ox     = pct(Q_Farad_Ox,   Q_Total_Ox);
            Percent_Q_Farad_Total  = pct(Q_farad_Total,Q_Total_Calc);
        
            Percent_Cap_Red        = pct(Q_Cap_Red,    Q_Total_Red);
            Percent_Cap_Ox         = pct(Q_Cap_Ox,     Q_Total_Ox);
            Percent_Q_Cap_Total    = pct(Q_Cap_Total,  Q_Total_Calc);
        
            Percent_Pseduocap_Red  = pct(Q_Pseudo_Red, Q_Total_Red);
            Percent_Pseduocap_Ox   = pct(Q_Pseudo_Ox,  Q_Total_Ox);
            Percent_Q_Pseudo_Total = pct(Q_pseudo_Total,Q_Total_Calc);
        
            rows = ["Reduction"; "Oxidation"; "Total"];
            col1 = [Q_red_fix;       Q_ox_fix;        Q_Total];
            col2 = [Q_Farad_Red;     Q_Farad_Ox;      Q_farad_Total];
            col3 = [Q_Cap_Red;       Q_Cap_Ox;        Q_Cap_Total];
            col4 = [Q_Pseudo_Red;    Q_Pseudo_Ox;     Q_pseudo_Total];
            col5 = [Percent_Q_Farad_Red; Percent_Q_Farad_Ox; Percent_Q_Farad_Total];
            col6 = [Percent_Cap_Red;     Percent_Cap_Ox;     Percent_Q_Cap_Total];
            col7 = [Percent_Pseduocap_Red; Percent_Pseduocap_Ox; Percent_Q_Pseudo_Total];
        
            vars = {'Total Charge (mA·s)','Faradaic Contribution (mA·s)', ...
                    'Capacitative Contribution (mA·s)','Pseudocapacitative Contribution (mA·s)', ...
                    'Faradaic Contribution (%)','Capacitative Contribution (%)', ...
                    'Pseudocapacitative Contribution (%)'};
        
            t = table(col1,col2,col3,col4,col5,col6,col7, ...
                      'VariableNames',vars,'RowNames',rows);
            disp(t)
        
            filename = app.FilepathEditField.Value;
            [path,name,~] = fileparts(filename);
            out = fullfile(path, [name,'_Charge_Contribution_Table.xlsx']);
            writetable(t, out, 'WriteRowNames', true);
            disp([name,'_Charge_Contribution_Table.xlsx written to path: ', path])
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'CVData')
                  p1 = app.p1;
                  h1 = app.h1;
                  delete(p1)
                  xpoint = app.UIAxes.CurrentPoint(1);
                  if isempty(h1)

                      h1 = xline(app.UIAxes,xpoint);
                      app.h1 = h1;
                      app.x1 = xpoint;
                  else
%                       h2 = xline(app.UIAxes,xpoint);
                      delete(h1)
                      exes = [app.x1 xpoint xpoint app.x1];
                      wize = [50 50 -50 -50];
                      p1 = patch(app.UIAxes,exes,wize,'blue');
                      alpha(p1,0.1)
                      h1 = [];
                      app.h1 = h1;
                      app.x1 = [];
                      app.p1 = p1;
                      app.xvals = [exes(1) exes(2)];
                  end
            end
        end

        % Button pushed function: SaveFigureButton
        function p_save(app, event)
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % Here you can make the savelocation variable to suit 
                    % your computer
                    hf = app.hf;
                    
                    filename = app.FilepathEditField.Value;
                    [path,~,~]=fileparts(filename);
                    
                    savelocation = path;

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    name = app.SaveEditField.Value;
                    filetype = app.SaveDropDown.Value;
                    
                    if filetype=='pdf'
                        filenamepdf=strcat(string(name), '.pdf');
                        orient(hf,'landscape')
%                         h.f(2).PaperSize = [10, 10];
                        hf.Renderer='painters'; % ensures the figure is actually saved as a vector graphic
                        print(hf,filenamepdf,'-dpdf','-bestfit')
                        movefile(filenamepdf,savelocation)
                        disp(strcat([".pdf figure saved to path: "], savelocation))
                    elseif filetype=='png'
                        filenamepng=strcat(string(name), '.png');
                        print(hf,filenamepng,'-dpng','-r1000')
                        movefile(filenamepng,savelocation)
                        disp(strcat([".png figure saved to path: "], savelocation))
                    elseif filetype=='fig'
                        figname=strcat(string(name), '.fig');
                        saveas(hf,name)
                        movefile(figname,savelocation)
                        disp(strcat([".fig file saved to path: "], savelocation))
                    end
        end

        % Value changed function: LowerVLimitEditField, 
        % ...and 1 other component
        function Edit_Limits(app, event)
            p_load(app)
            p_plot_CV(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {608, 608};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: OpenDataFormatterButton
        function Generate_Data_Window(app, event)
            FileFormatter
        end

        % Button pushed function: PeakpositioncorrectionButton
        function PeakpositioncorrectionButtonPushed(app, event)
            function results = p_plot_CV_step12_edgeprotect(app)
                V0  = app.V;
                ox  = app.ox;
                red = app.red;

                if size(ox,1) < size(ox,2) && size(red,1) < size(red,2), ox=ox.'; red=red.'; end
                [N,S] = size(ox);
            
                if isequal(size(V0),[N,S]), V = V0;
                elseif isvector(V0) && numel(V0)==N, V = repmat(V0(:),1,S);
                elseif isequal(size(V0),[S,N]),      V = V0.';
                else, error('V must be [N×S], [N×1]/[1×N], or [S×N]^T.');
                end
            
                % trying to fix limit issue
    %             rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
    % 
    %             for i = 1:length(rates_TT)
    %                 if rates_TT(i) == 1
    %                     appendredoxstart = find(~isnan(ox(:,i)),1,'first');
    %                     appendredoxend = find(~isnan(ox(:,i)),1,'last');
    %                     red(appendredoxstart,i) = ox(appendredoxstart,i);
    %                     red(appendredoxend,i) = ox(appendredoxend,i);
    %                 end
    %             end
    % 
    %             loV = min(min(ox(:,rates_TT)));
    %             hiV = max(max(ox(:,rates_TT)));

                % find loV and hiV
                loV = app.LowerVLimitEditField.Value;
                hiV = app.UpperVLimitEditField.Value;

                centralMask = @(v) (v>=loV+0.10*(hiV-loV)) & (v<=hiV-0.10*(hiV-loV));
            
                hasP=false(1,S); hasM=false(1,S);
                lo=max(1,round(0.15*N)); hi=min(N,round(0.85*N));

                for i=1:S
                    xi=ox(lo:hi,i); if numel(xi)>=5, hasP(i)=~isempty(findpeaks(xi,'MinPeakProminence',0.08*range(xi,'omitnan'))); end
                    ri=red(lo:hi,i);if numel(ri)>=5, hasM(i)=~isempty(findpeaks(-ri,'MinPeakProminence',0.08*range(ri,'omitnan'))); end
                end

                if ~any(hasP) && ~any(hasM)
                    local_plot_unified(app.UIAxes,V,ox,V,red,'Potential, E (V)','Current, \itI\rm/mA',[]); % helper function
                    results=struct('mode','non-faradaic (no calibration)'); return
                end
            
                [Epk_ox,Ipk_ox] = one_peak_per_scan(V,ox,true, centralMask);
                [Epk_rd,Ipk_rd] = one_peak_per_scan(V,red,false,centralMask);
                [~,R_ox,r2_ox,n_ox] = fit_E_equals_a_plus_RI(Epk_ox,Ipk_ox); % helper function
                [~,R_rd,r2_rd,n_rd] = fit_E_equals_a_plus_RI(Epk_rd,Ipk_rd); % helper function
            
                use_ox = n_ox>=2 && r2_ox>=0.98;
                use_rd = n_rd>=2 && r2_rd>=0.98;
                if use_ox && use_rd
                    if abs(R_ox) >= abs(R_rd), R = R_ox; else, R = R_rd; end
                elseif use_ox
                    R = R_ox;
                elseif use_rd
                    R = R_rd;
                else
                    R = 0; warning('No reliable R (R^2<0.98 or <2 points). Using R=0.');
                end

                E_dep_ox  = V - R*ox;
                E_dep_red = V + R*red;
            
                [haveNu,nu] = get_scan_rates(app,V);
                if haveNu
                    midBand = [loV+0.35*(hiV-loV), loV+0.65*(hiV-loV)];
                    mask_mid = V>=midBand(1) & V<=midBand(2);
                    M = nan(1,S);
                    for s=1:S
                        Ei = [E_dep_ox(mask_mid(:,s),s); E_dep_red(mask_mid(:,s),s)];
                        M(s) = mean(Ei,'omitnan');
                    end
                    good = isfinite(M) & isfinite(nu);
                    if nnz(good)>=2
                        X = [ones(nnz(good),1), nu(good).']; b = X \ M(good).';
                        b0 = b(1); b1 = b(2);
                    else
                        b0 = median(M,'omitnan'); b1 = 0;
                    end
                    base = b0 + b1.*nu;      % 1×S
                    E_cal_ox  = E_dep_ox  - repmat(base, N, 1);
                    E_cal_red = E_dep_red - repmat(base, N, 1);
                    note2 = sprintf('Step 2 applied: b0=%.4g, b1=%.4g.', b0, b1);
                else
                    E_cal_ox = E_dep_ox; E_cal_red = E_dep_red; b0=NaN; b1=NaN;
                    note2 = 'Step 2 skipped (no scan rate).';
                end
            
                protectFrac = 0.12;    transFrac = 0.05;
                protectW = max(30, round(protectFrac*N));
                transW   = max(10, round(transFrac*N));
                smooth01 = @(t) (3*t.^2 - 2*t.^3);
            
                wEdge = ones(N,1);
                if protectW>0
                    wEdge(1:protectW) = 0;
                    if protectW+transW <= N
                        t = linspace(0,1,transW).';
                        wEdge(protectW+(1:transW)) = smooth01(t);
                    end
                end
                if protectW>0
                    wEdge(end-protectW+1:end) = 0;
                    if protectW+transW <= N
                        t = linspace(1,0,transW).';
                        wEdge(end-protectW-transW+1 : end-protectW) = smooth01(t);
                    end
                end
            
                E_fin_ox  = V + repmat(wEdge,1,S) .* (E_cal_ox  - V);
                E_fin_red = V + repmat(wEdge,1,S) .* (E_cal_red - V);

                % store calibrated curves for deconvolution
                app.E_ox_all =  E_fin_ox;
                app.E_red_all = E_fin_red;

                % set calibration state to true
                app.hasCal = 1;
            
                labels_for_legend = [];
                sr = [];
                if isprop(app,'rate') && ~isempty(app.rate)
                    sr = app.rate;                 % accept any shape
                elseif exist('nu','var') && ~isempty(nu)
                    sr = nu;
                end
                if ~isempty(sr)
                    sr = sr(:).';                       % row
                    if numel(sr) < S,  sr(end+1:S) = sr(end); end  % pad if short
                    if numel(sr) > S,  sr = sr(1:S);   end         % trim if long
                    labels_for_legend = cellstr(string(sr) + " mV/s");  % cellstr -> legend-safe
                end
            
                local_plot_unified(app.UIAxes, E_fin_ox, ox, E_fin_red, red, ...
                                   'Calibrated potential, E_{cal} (V)', 'Current, \itI\rm/mA', ...
                                   labels_for_legend); % helper function
            
                if isgraphics(app.UIFigure)
                    setappdata(app.UIFigure,'E_ox',  E_fin_ox);
                    setappdata(app.UIFigure,'E_red', E_fin_red);
                end
            
                results = struct('R_ohms',R,'R2_ox',r2_ox,'R2_red',r2_rd, ...
                                 'n_points_ox',n_ox,'n_points_red',n_rd, ...
                                 'haveNu',haveNu,'nu',nu,'b0',b0,'b1',b1, ...
                                 'note',sprintf('%s | edge-protect: protect=%d (%.0f%%), trans=%d (%.0f%%)', ...
                                     note2, protectW,100*protectW/N, transW,100*transW/N));
                fprintf('Step 1 R=%.3g Ω (R² anodic=%.3f, cathodic=%.3f). %s\n', R, r2_ox, r2_rd, results.note);
            
                function [Epk,Ipk] = one_peak_per_scan(Vx,Ix,isAnodic,maskfun)
                    Epk = nan(1,S); Ipk = nan(1,S);
                    for i=1:S
                        cen = maskfun(Vx(:,i)); k = find(cen); if isempty(k), continue; end
                        if isAnodic, [p,loc] = findpeaks(Ix(k,i));
                        else,        [p,loc] = findpeaks(-Ix(k,i)); p = -p;
                        end
                        if ~isempty(p)
                            [~,m] = max(abs(p)); idx = k(loc(m));
                            Epk(i) = Vx(idx,i); Ipk(i) = Ix(idx,i);
                        end
                    end
                    keep = isfinite(Epk) & isfinite(Ipk);
                    if any(keep)
                        medI = median(Ipk(keep)); sI = 1.4826*mad(Ipk(keep),1);
                        bad = abs(Ipk - medI) > 3*sI;
                        Epk(bad) = NaN; Ipk(bad) = NaN;
                    end
                end
            
                function [a,Rfit,r2,n] = fit_E_equals_a_plus_RI(E,I)
                    E=E(:); I=I(:); good=isfinite(E)&isfinite(I); E=E(good); I=I(good); n=numel(E);
                    if n<2, a=NaN; Rfit=0; r2=NaN; return; end
                    [b,stats] = robustfit(I,E); a=b(1); Rfit=b(2);
                    ss_res = stats.s^2 * max(n-2,1); ss_tot = sum((E-mean(E)).^2);
                    r2 = max(0, 1 - ss_res/ss_tot);
                end
            
                function [ok,nu_] = get_scan_rates(app_,V_)
                    ok=false; nu_=[];
                    if isprop(app_,'ScanRates') && ~isempty(app_.ScanRates)
                        nu_=app_.ScanRates(:).'; ok = numel(nu_)==S && all(isfinite(nu_));
                        if ok, return; end
                    end
                    if isprop(app_,'Time') && ~isempty(app_.Time)
                        t = app_.Time;
                        if isequal(size(t),[N,S])
                            nu_=zeros(1,S);
                            for ii=1:S
                                r = abs(diff(V_(:,ii)))./diff(t(:,ii));
                                nu_(ii)=median(r(isfinite(r)&r>0));
                            end
                            ok = all(isfinite(nu_)) & all(nu_>0);
                        elseif isvector(t) && numel(t)==N
                            dt = diff(t(:)); nu_=zeros(1,S);
                            for ii=1:S
                                r = abs(diff(V_(:,ii)))./dt;
                                nu_(ii)=median(r(isfinite(r)&r>0));
                            end
                            ok = all(isfinite(nu_)) & all(nu_>0);
                        end
                    end
                end
            end
            
            % function for plotting dotted lines
            function local_plot_unified(ax, Xox, Iox, Xrd, Ird, xlab, ylab, legendLabels)
                cla(ax);
                hold(ax,'on');

                customColors = app.UIAxes.ColorOrder;

                [~,S] = size(Iox);
                if size(Xox,2)~=S, Xox = repmat(Xox(:,1),1,S); end
                if size(Xrd,2)~=S, Xrd = repmat(Xrd(:,1),1,S); end

                h = gobjects(1,S);
                for i = 1:S
                    colorIdx = mod(i-1, length(customColors)) + 1;
                    thisColor = customColors(colorIdx, :);

                    h(i) = plot(ax, Xox(:,i), Iox(:,i), 'LineWidth',3, 'Color', thisColor);
                    plot(ax, Xrd(:,i), Ird(:,i), 'LineWidth',3,'Color', thisColor);
                end

                % Append labels to legend
                if nargin>=8 && ~isempty(legendLabels)
                    legend(ax, h, legendLabels, 'Location','best', 'FontSize',24);
                else
                    legend(ax, h, "data"+string(1:S), 'Location','best', 'FontSize',24);
                end
            
                ax.FontWeight='bold'; ax.LineWidth=2;
                ax.XLabel.String = xlab;
                ax.YLabel.String = ylab;
                
                % X axis 
                xmin=min([Xox(:);Xrd(:)]); xmax=max([Xox(:);Xrd(:)]);
                spanX=max(xmax-xmin,eps); dx=max(abs(round(spanX/7,1,'significant')),1e-6);
                ax.XTick=(xmin-2*dx):dx:(xmax+2*dx); ax.XLim=[xmin-0.5*dx,xmax+0.5*dx];

                % Y axis
                ymin=min([Iox(:);Ird(:)]); ymax=max([Iox(:);Ird(:)]);
                spanY=max(ymax-ymin,eps); dy=max(abs(round(spanY/7,1,'significant')),1e-6);
                mint=round(floor(ymin/dy)*dy,2,'significant'); maxt=round(ceil(ymax/dy)*dy,2,'significant');
                ax.YLim=[mint-0.5*dy, maxt+0.5*dy]; ax.YTick=(mint-9*dy):dy:(maxt+9*dy);

                % Dotted original curves for all active columns (hide from legend)
                rates_TT = cell2mat(table2array(app.RateTable.Data(:,2)));
                cols  = find(rates_TT == 1);
                ncols = numel(cols);
                y_dub = [app.red(:,cols); app.ox(:,cols)];                 % (2N x ncols)
                X_dub = repmat(app.V(:), 2, 1);                           % (2N x 1)
                X_dub = repmat(X_dub, 1, ncols);                         % (2N x ncols)

                plot(ax, X_dub, y_dub, 'LineStyle', ':', 'LineWidth', 1, ...
                     'Tag', 'CVData', 'HandleVisibility','off');

                % % Peak markers (hide from legend)
                % plot(ax, V_ox_pp,  pp_ox,  'o', 'HandleVisibility','off');
                % plot(ax, V_red_pp, pp_red, 'o', 'HandleVisibility','off');

                % Create legend from DisplayName entries
                legend(ax,'show');
                hold(ax,'off');
        
                disp('Variable Rate CV Plotting Complete');
            end

            
            % -------------------- Run --------------------
            p_plot_CV_step12_edgeprotect(app);
        end

        % Button pushed function: SaveFigureButton_2
        function SaveFigureButton_2Pushed(app, event)
           % ---------- Resolve save folder ----------
            rawPath = string(app.FilepathEditField.Value);
            if strlength(rawPath) > 0
                [pathStr, ~, ~] = fileparts(rawPath);
            else
                pathStr = "";
            end
            if strlength(pathStr) == 0
                savelocation = pwd;            % fallback
            else
                savelocation = pathStr;
            end
            
            % ---------- Fixed name and filetype ----------
            fixedName = "CVs";
            filetype  = lower(string(app.pdfDropDown.Value));
            
            % ---------- Resolve a valid graphics target ----------
            target = [];   % this can be a figure OR axes for exportgraphics
            figForFig = []; % figure for saving .fig
            
            % Preferred: app.hf if it’s valid
            if isprop(app, 'hf') && ~isempty(app.hf) && isvalid(app.hf)
                target = app.hf;
            end
            
            % Fallbacks: try UIFigure, UIAxes, or current figure/axes
            if isempty(target) || ~isvalid(target)
                if isprop(app, 'UIAxes') && ~isempty(app.UIAxes) && isvalid(app.UIAxes)
                    target = app.UIAxes;        % UIAxes works with exportgraphics
                elseif isprop(app, 'UIFigure') && ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                    target = app.UIFigure;      % UIFigure works with exportgraphics
                elseif ~isempty(gcf) && isvalid(gcf)
                    target = gcf;
                end
            end
            
            % Make sure we have something valid
            if isempty(target) || ~isvalid(target)
                error('No valid figure or axes handle found to export.');
            end
            
            % For .fig we need a *figure* handle
            figForFig = ancestor(target, 'figure');
            if isempty(figForFig) || ~isvalid(figForFig)
                % If target is a figure already, ancestor returns empty—so handle that:
                if isa(target, 'matlab.ui.Figure') || isa(target, 'matlab.graphics.figure.Figure')
                    figForFig = target;
                end
            end
            
            % ---------- Save ----------
            switch filetype
                case 'pdf'
                    outfile = fullfile(savelocation, fixedName + ".pdf");
                    % Vector PDF; exportgraphics handles (ui)figure OR axes
                    exportgraphics(target, outfile, 'ContentType', 'vector');
                    disp("Saved PDF to: " + outfile);
            
                case 'png'
                    outfile = fullfile(savelocation, fixedName + ".png");
                    % High-resolution raster export
                    exportgraphics(target, outfile, 'Resolution', 1000);
                    disp("Saved PNG to: " + outfile);
            
                case 'fig'
                    if isempty(figForFig) || ~isvalid(figForFig)
                        error('Cannot save .fig: no valid figure found (only axes detected).');
                    end
                    outfile = fullfile(savelocation, fixedName + ".fig");
                    saveas(figForFig, outfile);
                    disp("Saved FIG to: " + outfile);
            
                otherwise
                    warning("Unknown filetype: %s", filetype);
            end
        end

        % Button pushed function: 
        % DeconvoluteCurrentwithoutpeakpositioncorrectionButton
        function DeconvoluteCurrentwithoutpeakpositioncorrectionButtonPushed(app, event)
            % set temp variable and turn off calibration
            temp = app.hasCal;
            app.hasCal = 0;

            % run deconvolution without calibration
            p_plot(app, event)
            
            % reset calibration
            app.hasCal = temp;
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            cla(app.UIAxes);
            delete(findall(app.UIAxes,'Tag','CVData'))

            % reset calibration, app will not use calibrated curves
            app.hasCal = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.BrowseDataButton = uibutton(app.LoadDataTab, 'push');
            app.BrowseDataButton.ButtonPushedFcn = createCallbackFcn(app, @p_browse, true);
            app.BrowseDataButton.Text = 'Browse Data';
            app.FilepathEditField = uieditfield(app.LoadDataTab, 'text');
            app.FilepathEditField.Value = 'Data File';
            app.LoadFormattedDataButton = uibutton(app.LoadDataTab, 'push');
            app.LoadFormattedDataButton.ButtonPushedFcn = createCallbackFcn(app, @p_load, true);
            app.LoadFormattedDataButton.Text = 'Load Formatted Data';
            app.LowerVLimitEditFieldLabel = uilabel(app.LoadDataTab);
            app.LowerVLimitEditFieldLabel.Text = 'Lower V Limit';
            app.LowerVLimitEditField = uieditfield(app.LoadDataTab, 'numeric');
            app.LowerVLimitEditField.ValueChangedFcn = createCallbackFcn(app, @Edit_Limits, true);
            app.LowerVLimitEditField.Value = -1;
            app.UpperVLimitEditFieldLabel = uilabel(app.LoadDataTab);
            app.UpperVLimitEditFieldLabel.Text = 'Upper V Limit';
            app.UpperVLimitEditField = uieditfield(app.LoadDataTab, 'numeric');
            app.UpperVLimitEditField.ValueChangedFcn = createCallbackFcn(app, @Edit_Limits, true);
            app.UpperVLimitEditField.Value = 1;
            app.FormatDataTab = uitab(app.TabGroup);
            app.FormatDataTab.Title = 'Format Data';
            app.OpenDataFormatterButton = uibutton(app.FormatDataTab, 'push');
            app.OpenDataFormatterButton.ButtonPushedFcn = createCallbackFcn(app, @Generate_Data_Window, true);
            app.OpenDataFormatterButton.Text = 'Open Data Formatter';
            app.Image = uiimage(app.FormatDataTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Schoetz_Logo.jpg');
            app.RateTable = uitable(app.LeftPanel);
            app.RateTable.ColumnName = {'Rates (mV/s)'; 'Include?'};
            app.RateTable.RowName = {};
            app.RateTable.ColumnEditable = [false true];
            app.RateTable.CellEditCallback = createCallbackFcn(app, @chooserates, true);
            app.PlotontoratemVsDropDownLabel = uilabel(app.LeftPanel);
            app.PlotontoratemVsDropDownLabel.Text = {'Plot onto '; 'rate (mV/s)'};
            app.PlotontoratemVsDropDown = uidropdown(app.LeftPanel);
            app.PlotontoratemVsDropDown.Items = {};
            app.PlotontoratemVsDropDown.Value = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Electrode potential, 𝑬 𝒗𝒔. ref/V')
            ylabel(app.UIAxes, 'Current, 𝐼/mA')
            app.VariableRateCVLabel = uilabel(app.RightPanel);
            app.VariableRateCVLabel.Text = 'Variable Rate CV';
            app.SaveFigureButton = uibutton(app.RightPanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @p_save, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.DeconvoluteCurrentwithpeakpositioncorrectionButton = uibutton(app.RightPanel, 'push');
            app.DeconvoluteCurrentwithpeakpositioncorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @p_plot, true);
            app.DeconvoluteCurrentwithpeakpositioncorrectionButton.Text = {'Deconvolute Current with'; ''; 'peak position correction'};
            app.GetTableButton = uibutton(app.RightPanel, 'push');
            app.GetTableButton.ButtonPushedFcn = createCallbackFcn(app, @Get_Capacitance, true);
            app.GetTableButton.Text = 'Get Table';
            app.SaveDropDown = uidropdown(app.RightPanel);
            app.SaveDropDown.Items = {'pdf', 'png', 'fig'};
            app.SaveDropDown.Value = 'pdf';
            app.SaveEditField = uieditfield(app.RightPanel, 'text');
            app.SaveEditField.Value = 'Save Deconvoluted Current Plot';
            app.PeakpositioncorrectionButton = uibutton(app.RightPanel, 'push');
            app.PeakpositioncorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @PeakpositioncorrectionButtonPushed, true);
            app.PeakpositioncorrectionButton.Text = 'Peak position correction';
            app.DeconvoluteCurrentwithoutpeakpositioncorrectionButton = uibutton(app.RightPanel, 'push');
            app.DeconvoluteCurrentwithoutpeakpositioncorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @DeconvoluteCurrentwithoutpeakpositioncorrectionButtonPushed, true);
            app.DeconvoluteCurrentwithoutpeakpositioncorrectionButton.Text = {'Deconvolute Current without'; ''; 'peak position correction'};
            app.SaveFigureButton_2 = uibutton(app.RightPanel, 'push');
            app.SaveFigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButton_2Pushed, true);
            app.SaveFigureButton_2.Text = 'Save Figure';
            app.pdfDropDown = uidropdown(app.RightPanel);
            app.pdfDropDown.Items = {'pdf', 'png', 'fig'};
            app.pdfDropDown.Value = 'pdf';
            app.ClearPlotButton = uibutton(app.RightPanel, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Current_Deconvolution_Cyclic_Voltammetry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end