classdef FileFormatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        SingleFileTab                  matlab.ui.container.Tab
        TextArea_4                     matlab.ui.control.TextArea
        ExcelSheetNamesDropDown        matlab.ui.control.DropDown
        ExcelSheetNamesDropDownLabel   matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        BrowseComputerButton           matlab.ui.control.Button
        MultipleFilesTab               matlab.ui.container.Tab
        FilesChosenLabel               matlab.ui.control.Label
        TextArea_3                     matlab.ui.control.TextArea
        SheetNamesoffirstfileDropDown  matlab.ui.control.DropDown
        SheetNamesoffirstfileDropDownLabel  matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        ConcatenateDataandLoadButton   matlab.ui.control.Button
        BrowseComputerButtonMulti      matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        RightPanel                     matlab.ui.container.Panel
        CurrentUnitsDropDown           matlab.ui.control.DropDown
        CurrentUnitsDropDownLabel      matlab.ui.control.Label
        AssigncolumnsofyourdatatothegivenheadingsLabel  matlab.ui.control.Label
        SaveFormattedFileButton        matlab.ui.control.Button
        CycleNumberDropDown            matlab.ui.control.DropDown
        CycleNumberDropDownLabel       matlab.ui.control.Label
        CurrentmADropDown              matlab.ui.control.DropDown
        CurrentmADropDownLabel         matlab.ui.control.Label
        PotentialVDropDown             matlab.ui.control.DropDown
        PotentialVDropDownLabel        matlab.ui.control.Label
        TimesDropDown                  matlab.ui.control.DropDown
        TimesDropDownLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        file % full file path+file name
        tabledataimported % Loaded Table
        headers % Table Headers
        time_col % time column mask
        voltage_col % voltage column mask
        current_col % current column mask
        nc_col % cycle number column mask
        current_unit % current unit conversion
        tableexport % .txt table file
        
        num_files % number of files in multiple file input
        filemulti % file names of multiple file input
        tableheights % to add to cycle number change indicies
        multitruth % one or zero depending on single or multi
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseComputerButtonMulti
        function browse_file_multi(app, event)
            [filename_cell, filepath] = uigetfile(fullfile('./',{'*.txt;*.xlsx;*.*'}),'Choose the ECLab Data File','MultiSelect','on');
            num_files = length(filename_cell);
            
            if num_files <= 1
                disp('Please choose more than one file.')
                return
            end            
            
            filepath = string(filepath);
            filename = string(filename_cell);
            
            app.TextArea_3.Value = filename;
            
            file = fullfile(filepath,filename);
            [~,~,ext] = fileparts(file(1));
            if contains(ext,'xlsx')
                [~,sheets] = xlsfinfo(file(1));
                app.SheetNamesoffirstfileDropDown.Items = sheets;
            end
            
            app.file = string(file);
            app.num_files = num_files;
        end

        % Button pushed function: BrowseComputerButton
        function browse_file(app, event)
            [filename, filepath] = uigetfile(fullfile('./',{'*.txt;*.xlsx;*.*'}),'Choose the ECLab Data File');
            if filename == 0
                set(app.EditField,'Value','Input Formatted Data')
                return
            else
                file = fullfile(filepath,filename);
                disp(['User selected file: ', filename]);
                set(app.EditField,'Value',file)
            end
            
            [~,~,ext] = fileparts(file);
            if contains(ext,'xlsx')
                [~,sheets] = xlsfinfo(file);
                app.ExcelSheetNamesDropDown.Items = sheets;
            end
            
%             app.file = string(file);
%             app.filepath = filepath;
%             app.filename = filename;
        end

        % Button pushed function: LoadDataButton
        function load_single(app, event)
            file = app.EditField.Value;
            
            if contains(file,'.xlsx')
%                 [~,sheets] = xlsfinfo(file);
%                 sheetno = app.ExcelSheetNumberSpinner.Value;
                    sheetsel = app.ExcelSheetNamesDropDown.Value;
%                 sheetsel = string(sheets(sheetno));
                disp(['Selected sheet name: ',sheetsel])
                tabledataimported = readtable(file,'Sheet',sheetsel,'PreserveVariableNames',true);
            else
                tabledataimported = readtable(file,'PreserveVariableNames',true,'FileType','text');
            end
            headers = tabledataimported.Properties.VariableNames;
            
            disp('Data loaded.')
            
            app.TimesDropDown.Items = headers;
            app.PotentialVDropDown.Items = headers;
            app.CurrentmADropDown.Items = headers;
            app.CycleNumberDropDown.Items = [headers, "One Cycle Only - No Column"];
            app.tabledataimported = tabledataimported;
            app.headers = headers;
            app.multitruth = 0;
            current_units(app,event)
            
            app.file = file;
        end

        % Button pushed function: SaveFormattedFileButton
        function save_formatted(app, event)
            tabledataimported = app.tabledataimported;
            file = app.file;
            multitruth = app.multitruth;
            header = app.headers;
            current_unit = app.current_unit;
            time_col = app.TimesDropDown.Value;
            voltage_col = app.PotentialVDropDown.Value;
            current_col = app.CurrentmADropDown.Value;
            nc_col = app.CycleNumberDropDown.Value;
            tableheights = app.tableheights;
%             app.MultipleFilesTab.
% use the multitruth logical to format cycle numbers
            
            [filepath,filename,~] = fileparts(file(1));
                        
            time_col_num = contains(header,time_col);
            time = table2array(tabledataimported(:,time_col_num));

            voltage_col_num = contains(header,voltage_col);
            V = table2array(tabledataimported(:,voltage_col_num));
            
            current_col_num = contains(header,current_col);
            I = table2array(tabledataimported(:,current_col_num)).*current_unit;
            if contains(nc_col, "One Cycle Only")
                cn = ones(size(I),'double');
            else
                nc_col_num = contains(header,nc_col);
                cn = table2array(tabledataimported(:,nc_col_num));
            end
            if multitruth == 1
                                
                addidx = tableheights';
                newaddidx=nan(size(addidx));
                for i=1:length(addidx)
                    newaddidx(i) = sum(addidx(1:i));
                end
                idx = find(diff(cn)~=0);
                idxend = length(cn);                
                idx = sort(unique([1; idx; idxend; newaddidx]));
                
                idx = idx(~isnan(idx));
                for i = 1:length(idx)-1
                    cn(idx(i):1:idx(i+1)) = i;
                end
%                 finds indices of all negative cn changes and sorts
%                 idx = find(diff(cn)<0);
%                 idxend = length(cn);
%                 idx = [idx; idxend];
%                 for i = 1:length(idx)
%                     maxvalue = cn(idx(i));
%                     cn_lower = cn(1:idx(i));
%                     cn_upper = cn(idx(i)+1:end)+maxvalue;
%                     cn = [cn_lower; cn_upper];
%                 end
%                 idx = find(diff(cn)>1);
%                 idxend = length(cn);
%                 idx = [idx; idxend];
%                 for i = 1:length(idx)
%                     prev_cn = cn(idx(i)-1);
%                     cn_now = prev_cn+1;
%                     cn_upper = cn(idx(i)+1:end)+maxvalue;
%                     maxvalue = cn(idx(i));
%                     
%                     
%                     cn = [cn_lower; cn_upper];
%                 end
%                 cn = newcn;
            end
            
            all_variables = [time, V, I, cn];
            all_variables = all_variables(~any(isnan(all_variables),2),:);
            time = all_variables(:,1);
            V = all_variables(:,2);
            I = all_variables(:,3);
            cn = all_variables(:,4);

            
            if current_unit ~= 1
                disp('Current converted to mA')
            end
            
            vars = {'time/s','Ewe/V','<I>/mA','cycle number'};
            

            if multitruth == 0
                name = strcat(file,'_formatted.txt');
                [~, namedisp, ~] = fileparts(file);
                tableexport = table(time,V,I,cn,'VariableNames',vars);
                writetable(tableexport,name,'WriteRowNames',true);
                disp(strcat("Table exported as ",namedisp))
            elseif multitruth == 1
                name = strcat(filepath,'/Formatted_multiple_input.txt');
                tableexport = table(time,V,I,cn,'VariableNames',vars);
                writetable(tableexport,name,'WriteRowNames',true);
                disp("Table exported as Formatted_multiple_input.txt")
            end
        end

        % Value changed function: TimesDropDown
        function set_t(app, event)
            value = app.TimesDropDown.Value;
            app.time_col = value;
            
        end

        % Value changed function: PotentialVDropDown
        function set_V(app, event)
            value = app.PotentialVDropDown.Value;
            app.voltage_col = value;
            
        end

        % Value changed function: CurrentmADropDown
        function set_I(app, event)
            value = app.CurrentmADropDown.Value;
            app.current_col = value;
            
        end

        % Value changed function: CycleNumberDropDown
        function set_cn(app, event)
            value = app.CycleNumberDropDown.Value;
            app.nc_col = value;
            
        end

        % Value changed function: CurrentUnitsDropDown
        function current_units(app, event)
            value = app.CurrentUnitsDropDown.Value;
            
            if value == 'A'
                current_unit = 1000;
            elseif value == 'mA'
                current_unit = 1;
            elseif value == 'µA'
                current_unit = 1/1000;
            end
            
            app.current_unit = current_unit;
        end

        % Button pushed function: ConcatenateDataandLoadButton
        function multi_load(app, event)
            file = app.file;
            num_files = app.num_files;
            
            sheetitems = string(app.SheetNamesoffirstfileDropDown.Items);
            sheetval = app.SheetNamesoffirstfileDropDown.Value;
            sheetno = find(sheetitems==sheetval);
                        
            alldata={};
            tableheights = nan(length(num_files));
            S = vartype('numeric');
            for i = 1:num_files
                if contains(file,'.xlsx')
                                     fprintf('Loading Excel file number %i\n',i)
                    alldata{i} = readtable(file(i),'Sheet',sheetno,'PreserveVariableNames',true);
                    if i == 1
                        fulltable = alldata{1};
                    else
                        fulltable = [fulltable; alldata{i}];                        
                    end
                elseif contains(file,'.txt')
                    fprintf('Loading text file number %i\n',i)
                    opts = detectImportOptions(file(i));
                    if contains(opts.VariableNames,'Var')
                        alldata{i} = readtable(file(i),'PreserveVariableNames',true,'NumHeaderLines',0);
                    else
                        alldata{i} = readtable(file(i),'PreserveVariableNames',true);
                    end
                    headers = alldata{1}.Properties.VariableNames;
                    
                    tableheights(i) = height(alldata{i});
                    tablewidth = width(alldata{i});
                    
                    if i == 1
                        fulltable = alldata{1};
                    else
                        fulltable = [fulltable; alldata{i}];                        
                    end
                else
                    fprintf('Loading text file number %i\n',i)
                    alldata{i} = readtable(file(i),'FileType','text','PreserveVariableNames',true);
                    headers = alldata{1}.Properties.VariableNames;
                    
                    tableheights(i) = height(alldata{i});
                    tablewidth = width(alldata{i});
                    
                    if i == 1
                        fulltable = alldata{1};
                    else
                        fulltable = [fulltable; alldata{i}];                        
                    end
                end
            end
            
            fulltable = fulltable(:,S);
            headers = fulltable.Properties.VariableNames;
            app.TimesDropDown.Items = headers;
            app.PotentialVDropDown.Items = headers;
            app.CurrentmADropDown.Items = headers;
            app.CycleNumberDropDown.Items = [headers, "One Cycle Only - No Column"];
            app.tabledataimported = fulltable;
            app.headers = headers;
            app.tableheights = tableheights;
            app.multitruth = 1;
            
            disp('Data loaded.')
            
            current_units(app,event)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SingleFileTab = uitab(app.TabGroup);
            app.SingleFileTab.Title = 'Single File';
            app.BrowseComputerButton = uibutton(app.SingleFileTab, 'push');
            app.BrowseComputerButton.ButtonPushedFcn = createCallbackFcn(app, @browse_file, true);
            app.BrowseComputerButton.Text = 'Browse Computer';
            app.EditField = uieditfield(app.SingleFileTab, 'text');
            app.EditField.Value = 'Or paste file path here';
            app.LoadDataButton = uibutton(app.SingleFileTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @load_single, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ExcelSheetNamesDropDownLabel = uilabel(app.SingleFileTab);
            app.ExcelSheetNamesDropDownLabel.Text = {'Excel Sheet '; 'Names'; ''};
            app.ExcelSheetNamesDropDown = uidropdown(app.SingleFileTab);
            app.ExcelSheetNamesDropDown.Items = {'N/A'};
            app.ExcelSheetNamesDropDown.Value = 'N/A';
            app.TextArea_4 = uitextarea(app.SingleFileTab);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'If the different CV rates are spread across multiple files (of the same format), you can concatenate and load them simultaneously in the "Multiple Files" tab.'};
            app.MultipleFilesTab = uitab(app.TabGroup);
            app.MultipleFilesTab.Title = 'Multiple Files';
            app.TextArea = uitextarea(app.MultipleFilesTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Please select all files to import'};
            app.BrowseComputerButtonMulti = uibutton(app.MultipleFilesTab, 'push');
            app.BrowseComputerButtonMulti.ButtonPushedFcn = createCallbackFcn(app, @browse_file_multi, true);
            app.BrowseComputerButtonMulti.Text = 'Browse Computer';
            app.ConcatenateDataandLoadButton = uibutton(app.MultipleFilesTab, 'push');
            app.ConcatenateDataandLoadButton.ButtonPushedFcn = createCallbackFcn(app, @multi_load, true);
            app.ConcatenateDataandLoadButton.Text = 'Concatenate Data and Load';
            app.TextArea_2 = uitextarea(app.MultipleFilesTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Note: All files should be in the same folder and be formatted identically'};
            app.SheetNamesoffirstfileDropDownLabel = uilabel(app.MultipleFilesTab);
            app.SheetNamesoffirstfileDropDownLabel.Text = {'Sheet Names '; '(of first file)'};
            app.SheetNamesoffirstfileDropDown = uidropdown(app.MultipleFilesTab);
            app.SheetNamesoffirstfileDropDown.Items = {'N/A'};
            app.SheetNamesoffirstfileDropDown.Value = 'N/A';
            app.TextArea_3 = uitextarea(app.MultipleFilesTab);
            app.FilesChosenLabel = uilabel(app.MultipleFilesTab);
            app.FilesChosenLabel.Text = 'Files Chosen:';
            app.RightPanel = uipanel(app.GridLayout);
            app.TimesDropDownLabel = uilabel(app.RightPanel);
            app.TimesDropDownLabel.Text = 'Time (s)';
            app.TimesDropDown = uidropdown(app.RightPanel);
            app.TimesDropDown.Items = {};
            app.TimesDropDown.ValueChangedFcn = createCallbackFcn(app, @set_t, true);
            app.TimesDropDown.Value = {};
            app.PotentialVDropDownLabel = uilabel(app.RightPanel);
            app.PotentialVDropDownLabel.Text = 'Potential (V)';
            app.PotentialVDropDown = uidropdown(app.RightPanel);
            app.PotentialVDropDown.Items = {};
            app.PotentialVDropDown.ValueChangedFcn = createCallbackFcn(app, @set_V, true);
            app.PotentialVDropDown.Value = {};
            app.CurrentmADropDownLabel = uilabel(app.RightPanel);
            app.CurrentmADropDownLabel.Text = 'Current (mA)';
            app.CurrentmADropDown = uidropdown(app.RightPanel);
            app.CurrentmADropDown.Items = {};
            app.CurrentmADropDown.ValueChangedFcn = createCallbackFcn(app, @set_I, true);
            app.CurrentmADropDown.Value = {};
            app.CycleNumberDropDownLabel = uilabel(app.RightPanel);
            app.CycleNumberDropDownLabel.Text = 'Cycle Number';
            app.CycleNumberDropDown = uidropdown(app.RightPanel);
            app.CycleNumberDropDown.Items = {};
            app.CycleNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @set_cn, true);
            app.CycleNumberDropDown.Value = {};
            app.SaveFormattedFileButton = uibutton(app.RightPanel, 'push');
            app.SaveFormattedFileButton.ButtonPushedFcn = createCallbackFcn(app, @save_formatted, true);
            app.SaveFormattedFileButton.Text = 'Save Formatted File';
            app.AssigncolumnsofyourdatatothegivenheadingsLabel = uilabel(app.RightPanel);
            app.AssigncolumnsofyourdatatothegivenheadingsLabel.Text = 'Assign columns of your data to the given headings';
            app.CurrentUnitsDropDownLabel = uilabel(app.RightPanel);
            app.CurrentUnitsDropDownLabel.Text = 'Current Units';
            app.CurrentUnitsDropDown = uidropdown(app.RightPanel);
            app.CurrentUnitsDropDown.Items = {'mA', 'A', 'µA'};
            app.CurrentUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @current_units, true);
            app.CurrentUnitsDropDown.Value = 'mA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FileFormatter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end